classdef solverAppVer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        Switch             matlab.ui.control.Switch
        gamma1SliderLabel  matlab.ui.control.Label
        gamma1Slider       matlab.ui.control.Slider
        gamma2SliderLabel  matlab.ui.control.Label
        gamma2Slider       matlab.ui.control.Slider
        Tgamma1            matlab.ui.control.NumericEditField
        Tgamma2            matlab.ui.control.NumericEditField
        Lamp               matlab.ui.control.Lamp
        KEditFieldLabel    matlab.ui.control.Label
        KEditField         matlab.ui.control.NumericEditField
        JEditFieldLabel    matlab.ui.control.Label
        JEditField         matlab.ui.control.NumericEditField
        ResetAxesButton    matlab.ui.control.Button
        ResetButton        matlab.ui.control.Button
        NEditFieldLabel    matlab.ui.control.Label
        NEditField         matlab.ui.control.NumericEditField
        DropDown           matlab.ui.control.DropDown
        SaveStateButton    matlab.ui.control.Button
        LoadStateButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        N = 100;    % Description
        J = 0.8;
        K = -0.5;
        gamma1 = 2/3;
        gamma2 = -1/3;
        dim = 2;
        stepSize = 1;
        timeSteps = 1000;
        y00 = [];
        options = odeset('RelTol',1e-6,'AbsTol',1e-6,'OutputFcn',@solverAppVer2.plotode); %% Need to change the function name
        tStart = 0;
        tEnd = 100000;
        flag = true;
        reset = false;
    end
    
    methods (Static)
        
        function dy = swarSims(~,y,dim,N,w,K,J,gamma1,gamma2,varargin)
            dy=zeros((dim+1)*N,1);
            v = zeros(N,dim);
            
            pts = reshape(y(1:dim*N),N,dim);
            PTS = permute(repmat(pts,1,1,N),[3 1 2]);
            PTSdiff = PTS - permute(PTS, [2 1 3]);
            
            
            Y = 1./internal.stats.pdistmex(pts','euc',[]);
            invDis = zeros(N,N);
            invDis(tril(true(N),-1)) = Y;
            invDis = invDis + invDis';
            invDis = invDis + eye(N);
            
            
            phi = y(dim*N + 1:end);
            PHIdiff = phi' - phi;
            
            A_pos = (1 + J*cos(PHIdiff)).*invDis - invDis.^2;
            dy(dim*N + 1:(dim+1)*N) = w' + (K/N)*sum(invDis.*(gamma1*sin(PHIdiff) + gamma2*sin(2*PHIdiff)),2);
            v_pos = v + (1/N)*reshape(sum(A_pos.*PTSdiff,2),N,dim);
            dy(1:dim*N) = reshape(v_pos,dim*N,1);
        end
        
        function status = plotode(~,y,flag,varargin)
            persistent TARGET_FIGURE TARGET_AXES N
            status = 0;
            m = size(y,2);
            if nargin < 3 || isempty(flag)
                flag = '';
            elseif isstring(flag) && isscalar(flag)
                flag = char(flag);
            end
            switch(flag)
                case ''
                    if ~ishandle(TARGET_FIGURE) || ~ishandle(TARGET_AXES)
                        status = 1;
                        return
                    end
                    ud = get(TARGET_FIGURE,'UserData');
                    minx = min(y(1:N,:),[],'all');
                    maxx = max(y(1:N,:),[],'all');
                    miny = min(y(N+1:2*N,:),[],'all');
                    maxy = max(y(N+1:2*N,:),[],'all');
                    if ud.axesData(1,1) > minx
                        ud.axesData(1,1) = minx;
                    end
                    if ud.axesData(1,2) < maxx
                        ud.axesData(1,2) = maxx;
                    end
                    if ud.axesData(2,1) > miny
                        ud.axesData(2,1) = miny;
                    end
                    if ud.axesData(2,2) < maxy
                        ud.axesData(2,2) = maxy;
                    end

                    TARGET_AXES.XLim = ud.axesData(1,:);
                    TARGET_AXES.YLim = ud.axesData(2,:);
                    set(TARGET_FIGURE,'UserData',ud);
                    if ud.stop == 1
                        status = 1;
                    end
                    if m~= 1
                        for k = 1:m
                            ud.ScatterPoints.XData = y(1:N,k);
                            ud.ScatterPoints.YData = y(N+1:2*N,k);
                            ud.ScatterPoints.CData = mod(y(2*N+1:end,k),2*pi);
                            drawnow limitrate
                        end
                    else
                        ud.ScatterPoints.XData = y(1:N,1);
                        ud.ScatterPoints.YData = y(N+1:2*N,1);
                        ud.ScatterPoints.CData = mod(y(2*N+1:end,1),2*pi);
                        drawnow limitrate
                    end
                    
                case 'init'
                    appData = varargin{end};
                    TARGET_FIGURE = appData.UIFigure;
                    TARGET_AXES = appData.UIAxes;
                    ud = get(TARGET_FIGURE,'UserData');
                    N = length(y(:,1))/3;
                    if ~isfield(ud,'ScatterPoints')
                        ud.ScatterPoints = scatter(TARGET_AXES,y(1:N,1),y(N+1:2*N,1),[],y(2*N+1:end,1),'filled');
                        %colorbar(TARGET_AXES)
                    else
                        ud.ScatterPoints.XData = y(1:N,1);
                        ud.ScatterPoints.YData = y(N+1:2*N,1);
                        ud.ScatterPoints.CData = mod(y(2*N+1:end,1),2*pi);
                    end
                    if ~isfield(ud,'figStart')
                        %TARGET_AXES.Colormap = hsv(256);
                        set(TARGET_AXES,'DataAspectRatio',[1 1 1]);
                        set(TARGET_FIGURE,'Colormap',hsv);
                        set(TARGET_AXES,'CLim',[0 2*pi]);
                        set(TARGET_AXES,'Box','on');
                        obj = colorbar(TARGET_AXES);
                        obj.TickLabelInterpreter = 'latex';
                        obj.XTickLabel = {'$0$','$\frac{\pi}{4}$','$\frac{\pi}{2}$','$\frac{3\pi}{4}$','$\pi$',...
                            '$\frac{5\pi}{4}$','$\frac{3\pi}{2}$','$\frac{7\pi}{4}$','$2\pi$'};
                        obj.XTick = 0:pi/4:2*pi;
                        obj.FontSize = 13;
                        ud.figStart = 1;
                        %appData.gamma1Slider.Value = appData.gamma1;
                    end
                    if ~isfield(ud,'axesData')
                        ud.axesData = zeros(2,2);
                    end
                    ud.stop = 0;
                    set(TARGET_FIGURE,'UserData',ud);
                    drawnow update
                    
                case 'done'
                    
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            reset(RandStream.getGlobalStream,1);
            rand_init = 76599;
            var = randn(rand_init,2);
            clear var
            
            app.DropDown.Value = 'Random';
            if strcmp(app.DropDown.Value,'Random')
                app.y00 = rand((app.dim+1)*app.N,1);
                app.y00(app.dim*app.N + 1:end) = 2*pi*app.y00(app.dim*app.N + 1:end);
            end
            app.UIAxes.Box = 'on';
            app.gamma1Slider.Value = app.gamma1;
            app.gamma2Slider.Value = app.gamma2;
            app.Tgamma1.Value = app.gamma1;
            app.Tgamma2.Value = app.gamma2;
            app.Lamp.Color = 'red';
            app.KEditField.Value = app.K;
            app.JEditField.Value = app.J;
            app.NEditField.Value = app.N;
            app.UIAxes.Color = 'k';
            %profile on
            %app.UIAxes.Colormap = hsv(256);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            %             disp(value);
            %             if strcmp(value,'On')
            %                 disp('Run this code');
            %             end
            if strcmp(value,'On')
                app.Lamp.Color = 'green';
                app.flag = true;
                while app.tStart < app.tEnd && app.flag
                    [T,y_full] = ode45(@app.swarSims, [app.tStart:app.stepSize/5:app.tEnd],...
                        app.y00,app.options, app.dim, app.N, zeros(1,app.N), app.K, app.J, app.gamma1, app.gamma2,app);

clc
app.tStart = T(end);
                    if ~app.reset
                        app.y00 = y_full(end,:);
                    else
                        app.reset = false;
                    end
                    if length(T) > 4
                        app.options = odeset(app.options,'InitialStep',T(end) - T(end-4),'MaxStep',T(end)-T(1));
                    end
                    clear T
                    clear y_full
                end
                if ~app.flag && strcmp(app.Switch.Value,'On')
                    app.Switch.Value = 'Off';
                    app.Lamp.Color = 'red';
                end
            else
                app.Lamp.Color = 'red';
                app.flag = false;
                ud = get(app.UIFigure,'UserData');
                ud.stop = 1;
                set(app.UIFigure,'UserData',ud);
                %disp('NOTHING HAPPENDED');
            end
        end

        % Value changed function: gamma1Slider
        function gamma1SliderValueChanged(app, event)
            value = app.gamma1Slider.Value;
            app.Tgamma1.Value = value;
            app.gamma1 = value;
            ud = get(app.UIFigure,'UserData');
            ud.stop = 1;
            ud.gamma1 = value;
            set(app.UIFigure,'UserData',ud);
        end

        % Value changed function: gamma2Slider
        function gamma2SliderValueChanged(app, event)
            value = app.gamma2Slider.Value;
            app.Tgamma2.Value = value;
            app.gamma2 = value;
            ud = get(app.UIFigure,'UserData');
            ud.stop = 1;
            ud.gamma2 = value;
            set(app.UIFigure,'UserData',ud);
        end

        % Value changed function: Tgamma1
        function Tgamma1ValueChanged(app, event)
            value = app.Tgamma1.Value;
            app.gamma1Slider.Value = value;
            app.gamma1SliderValueChanged(app);
        end

        % Value changed function: Tgamma2
        function Tgamma2ValueChanged(app, event)
            value = app.Tgamma2.Value;
            app.gamma2Slider.Value = value;
            app.gamma2SliderValueChanged(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %disp('HERERERER');
            app.Switch.Value = 'Off';
            app.reset = true;
            app.flag = false;
            app.SwitchValueChanged(app);
            drawnow;
            %profsave;
            delete(app);
            
            %disp('finalllllyyyy')
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            value = app.KEditField.Value;
            app.K = value;
            ud = get(app.UIFigure,'UserData');
            ud.stop = 1;
            set(app.UIFigure,'UserData',ud);
        end

        % Value changed function: JEditField
        function JEditFieldValueChanged(app, event)
            value = app.JEditField.Value;
            app.J = value;
            ud = get(app.UIFigure,'UserData');
            ud.stop = 1;
            set(app.UIFigure,'UserData',ud);
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            ud = get(app.UIFigure,'UserData');
            ud.axesData = zeros(2,2);
            set(app.UIFigure,'UserData',ud);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if strcmp(app.Switch.Value,'On')
                app.Switch.Value = 'Off';
                app.SwitchValueChanged(app);
                %app.ResetButtonPushed();
            end
            app.gamma1 = 2/3;
            app.gamma2 = -1/3;
            app.J = 0.8;
            app.K = -0.5;
            app.startupFcn();
            app.reset = true;
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            if strcmp(app.Switch.Value,'Off')
                app.N = value;
                app.startupFcn();
            else
                app.Switch.Value = 'Off';
                app.SwitchValueChanged(app);
                app.N = value;
                app.startupFcn();
                app.Switch.Value = 'On';
                app.SwitchValueChanged(app);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'Random'
                    if strcmp(app.Switch.Value,'On')
                        app.flag = false;
                        ud = get(app.UIFigure,'UserData');
                        ud.stop = 1;
                        set(app.UIFigure,'UserData',ud);
                        %app.SwitchValueChanged(app,event);
                    end
                    app.y00 = rand((app.dim+1)*app.N,1);
                    app.y00(app.dim*app.N + 1:end) = 2*pi*app.y00(app.dim*app.N + 1:end);
                    app.reset = true;
                case 'Single Cluster'
                    if strcmp(app.Switch.Value,'On')
                        app.flag = false;
                        ud = get(app.UIFigure,'UserData');
                        ud.stop = 1;
                        set(app.UIFigure,'UserData',ud);
                    end
                    pos = pointsInC(0,0,1,app.N);
                    pos(:,3) = 0;
                    app.y00 = reshape(pos,1,[]);
                    app.reset = true;
                case 'Two Cluster'
                    if strcmp(app.Switch.Value,'On')
                        app.Switch.Value = 'Off';
                        app.SwitchValueChanged(app);
                    end
                    n1 = floor(app.N/2);
                    n2 = app.N - n1;
                    pos1 = pointsInC(0,0,1,n1);
                    pos2 = pointsInC(7,0,1,n2);
                    
                    phase1 = zeros(n1,1);
                    phase2 = pi*ones(n2,1);
                    app.y00 = reshape([[pos1;pos2],[phase1;phase2]],1,[]);
                    app.reset = true;
                case 'Single Cluster Rand Phase'
                    if strcmp(app.Switch.Value,'On')
                        app.Switch.Value = 'Off';
                        app.SwitchValueChanged(app);
                    end
                    pos = pointsInC(0,0,1,app.N);
                    pos(:,3) = rand(app.N,1)*10^-3;
                    app.y00 = reshape(pos,1,[]);
                    app.reset = true;
            end
        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)
            % This callback saves the current state of the system
            x = app.UIAxes.Children.XData;
            y = app.UIAxes.Children.YData;
            c = app.UIAxes.Children.CData;
            data.yData = [x,y,c'];
            data.gamma1 = app.gamma1;
            data.gamma2 = app.gamma2;
            data.N = app.N;
            data.K = app.K;
            data.J = app.J;
            data.Distribution = app.DropDown.Value;
            save(sprintf('gamma1_%0.2g_gamma2_%0.2g_N_%d_K_%0.2g_J_%0.2g.mat',app.gamma1,...
                app.gamma2,app.N,app.K,app.J),"data");
        end

        % Button pushed function: LoadStateButton
        function LoadStateButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            load(strcat(path,file));
            %disp('I am here!!')
            app.y00 = data.yData;
            app.N = data.N;
            app.NEditField.Value = app.N;
            app.J = data.J;
            app.JEditField.Value = app.J;
            
            app.gamma1 = data.gamma1;
            app.gamma1Slider.Value = app.gamma1;
            app.Tgamma1.Value = app.gamma1;
            
            app.gamma2 = data.gamma2;
            app.gamma2Slider.Value = app.gamma2;
            app.Tgamma2.Value = app.gamma2;
            
            app.K = data.K;
            app.KEditField.Value = app.K;
            app.DropDown.Value = data.Distribution;
            clear data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.gamma1SliderLabel = uilabel(app.Panel);
            app.gamma1SliderLabel.Text = 'gamma1';
            app.gamma1Slider = uislider(app.Panel);
            app.gamma1Slider.Limits = [-1 1];
            app.gamma1Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.gamma1Slider.ValueChangedFcn = createCallbackFcn(app, @gamma1SliderValueChanged, true);
            app.gamma2SliderLabel = uilabel(app.Panel);
            app.gamma2SliderLabel.Text = 'gamma2';
            app.gamma2Slider = uislider(app.Panel);
            app.gamma2Slider.Limits = [-1 1];
            app.gamma2Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.gamma2Slider.ValueChangedFcn = createCallbackFcn(app, @gamma2SliderValueChanged, true);
            app.Tgamma1 = uieditfield(app.Panel, 'numeric');
            app.Tgamma1.Limits = [-1 1];
            app.Tgamma1.ValueChangedFcn = createCallbackFcn(app, @Tgamma1ValueChanged, true);
            app.Tgamma2 = uieditfield(app.Panel, 'numeric');
            app.Tgamma2.Limits = [-1 1];
            app.Tgamma2.ValueChangedFcn = createCallbackFcn(app, @Tgamma2ValueChanged, true);
            app.Lamp = uilamp(app.Panel);
            app.KEditFieldLabel = uilabel(app.Panel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.Panel, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.JEditFieldLabel = uilabel(app.Panel);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.Panel, 'numeric');
            app.JEditField.ValueChangedFcn = createCallbackFcn(app, @JEditFieldValueChanged, true);
            app.ResetAxesButton = uibutton(app.Panel, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NEditFieldLabel = uilabel(app.Panel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Panel, 'numeric');
            app.NEditField.Limits = [5 1000];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 100;
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Random', 'Single Cluster', 'Two Cluster', 'Single Cluster Rand Phase'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Random';
            app.SaveStateButton = uibutton(app.Panel, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Text = 'Save State';
            app.LoadStateButton = uibutton(app.Panel, 'push');
            app.LoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateButtonPushed, true);
            app.LoadStateButton.Text = 'Load State';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solverAppVer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end